classdef Bitplane < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        BitPlaneLabel           matlab.ui.control.Label
        BrowseInputImageButton  matlab.ui.control.Button
        SaveImagesButton        matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_5                matlab.ui.control.UIAxes
        UIAxes_6                matlab.ui.control.UIAxes
        UIAxes_7                matlab.ui.control.UIAxes
        UIAxes_8                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseInputImageButton
        function browse(app, event)
            global one
            global two
            global three
            global four
            global five
            global six
            global seven
            global eight
            try
            [filename,filepath] = uigetfile({'*.jpg';'*.png'});
            fullName = fullfile(filepath, filename);
            imageFile = imread(fullName);
            imagegray = rgb2gray(imageFile);
            B = bitget(imagegray, 1);
            one = logical(B);
            imshow(logical(B),'Parent', app.UIAxes);
            B = bitget(imagegray, 2);
            two = logical(B);
            imshow(logical(B),'Parent', app.UIAxes_2);
            B = bitget(imagegray, 3);
            three = logical(B);
            imshow(logical(B),'Parent', app.UIAxes_3);
            B = bitget(imagegray, 4);
            four = logical(B);
            imshow(logical(B),'Parent', app.UIAxes_4);
            B = bitget(imagegray, 5);
            five = logical(B);
            imshow(logical(B),'Parent', app.UIAxes_5);
            B = bitget(imagegray, 6);
            six = logical(B);
            imshow(logical(B),'Parent', app.UIAxes_6);
            B = bitget(imagegray, 7);
            seven = logical(B);
            imshow(logical(B),'Parent', app.UIAxes_7);
            B = bitget(imagegray, 8);
            eight = logical(B);
            imshow(logical(B),'Parent', app.UIAxes_8);
             catch 
                msg3 ='Please select an Image';
                errordlg(msg3);
            end
        end

        % Button pushed function: SaveImagesButton
        function save(app, event)
            global one
            global two
            global three
            global four
            global five
            global six
            global seven
            global eight
            folder = uigetdir();
            imwrite (one, fullfile(folder, 'one.bmp'));
            imwrite (two, fullfile(folder, 'two.bmp'));
            imwrite (three, fullfile(folder, 'three.bmp'));
            imwrite (four, fullfile(folder, 'four.bmp'));
            imwrite (five, fullfile(folder, 'five.bmp'));
            imwrite (six, fullfile(folder, 'six.bmp'));
            imwrite (seven, fullfile(folder, 'seven.bmp'));
            imwrite (eight, fullfile(folder, 'eight.bmp'));
        end

        % Callback function
        function exit(app, event)
     
        end

        % Close request function: UIFigure
        function close(app, event)
             clear global;
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            app.BitPlaneLabel = uilabel(app.UIFigure);
            app.BitPlaneLabel.Text = 'Bit Plane';
            app.BrowseInputImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.BrowseInputImageButton.Text = 'Browse Input Image';
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '1')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '3')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '2')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '5')
            app.UIAxes_4.Visible = 'off';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '4')
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, '7')
            app.UIAxes_6.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, '8')
            app.UIAxes_7.Visible = 'off';
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, '6')
            app.UIAxes_8.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bitplane

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end