classdef ImageSet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ImageSetOperationsLabel         matlab.ui.control.Label
        BrowseImage1Button              matlab.ui.control.Button
        BrowseImage2Button              matlab.ui.control.Button
        SaveImagesButton                matlab.ui.control.Button
        UnionCheckBox                   matlab.ui.control.CheckBox
        IntersectionCheckBox            matlab.ui.control.CheckBox
        complementCheckBox              matlab.ui.control.CheckBox
        SetDifferenceCheckBox           matlab.ui.control.CheckBox
        SymmetricSetDifferenceCheckBox  matlab.ui.control.CheckBox
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImage1Button
        function image1search(app, event)
            global image1
            try
                [filename,filepath] = uigetfile({'*.bmp; *.png; *.jpeg'});
                fullName = fullfile(filepath, filename);
                image1 = imread(fullName);
                app.BrowseImage1Button.Enable = 'off';
            catch
                msg3 ='Please select an Image 1';
                errordlg(msg3);
                app.BrowseImage1Button.Enable = 'on';
                return;
            end
        end

        % Button pushed function: BrowseImage2Button
        function search2(app, event)
            global image2
            global image1
            if isempty(image1)
                msg = 'Please search for Image 1 first.';
                errordlg(msg);
                app.BrowseImage2Button.Enable = 'on';
                return;
            end
            try
                [filename,filepath] = uigetfile({'*.bmp; *.png; *.jpeg'});
                fullName = fullfile(filepath, filename);
                img2 = imread(fullName);
                app.BrowseImage2Button.Enable = 'off';
                [rows1, cols1, ~] = size(image1);
                [rows2, cols2, ~] = size(img2);
                if rows2 ~= rows1 || cols1 ~= cols2
                    img2 = imresize(img2, [rows1 cols1]);
                end
                if ndims(image1) == 2 && islogical(image1)
                    if ndims(img2) == 2 && islogical(img2)
                        image2 = img2;
                    else
                        msg2 = 'Image 1 is Binary. Images must be of the same type.';
                        errordlg(msg2);
                        app.BrowseImage2Button.Enable = 'on';
                        return;
                    end
                end
                if ndims(img2) == 2 && islogical(img2)
                    if ndims(image1) ~= 2 && ~islogical(image1)
                        msg5 = 'Image 1 is not Binary. Images must be of the same type.';
                        errordlg(msg5);
                        app.BrowseImage2Button.Enable = 'on';
                        return;
                    end
                end
                image2 = img2;
            catch
                msg3 ='Please select an Image 2';
                errordlg(msg3);
                app.BrowseImage2Button.Enable = 'on';
            end
            
        end

        % Value changed function: UnionCheckBox
        function un(app, event)
            global image1
            global image2
            global u
            u = max(image1, image2);
            imshow(image1,'Parent', app.UIAxes);
            imshow(image2, 'Parent',app.UIAxes_2);
            imshow(u, 'Parent',app.UIAxes_3);
        end

        % Value changed function: IntersectionCheckBox
        function intersection(app, event)
            global image1
            global image2
            global i
            i = min(image1, image2);
            imshow(image1,'Parent', app.UIAxes);
            imshow(image2, 'Parent',app.UIAxes_2);
            imshow(i, 'Parent',app.UIAxes_3);
        end

        % Value changed function: SetDifferenceCheckBox
        function setdifference(app, event)
            global image1
            global image2
            global sd
            sd = image1-image2;
            imshow(image1,'Parent', app.UIAxes);
            imshow(image2, 'Parent',app.UIAxes_2);
            imshow(sd, 'Parent',app.UIAxes_3);
            
        end

        % Value changed function: SymmetricSetDifferenceCheckBox
        function symSetDifference(app, event)
            global image1
            global image2
            global ssd
            one = image1 - image2;
            two = image2 - image1;
            ssd = one - two;
            imshow(image1,'Parent', app.UIAxes);
            imshow(image2, 'Parent',app.UIAxes_2);
            imshow(ssd, 'Parent',app.UIAxes_3);
            
        end

        % Value changed function: complementCheckBox
        function comp(app, event)
            global image1
            global c
            c = imcomplement(image1);
            imshow(image1,'Parent', app.UIAxes);
            hide = imread("hideimages\hideset.PNG");
            imshow(hide, 'Parent',app.UIAxes_2);
            imshow(c, 'Parent',app.UIAxes_3);
        end

        % Callback function
        function exit(app, event)
            clear global;
            app.delete;
        end

        % Button pushed function: SaveImagesButton
        function save(app, event)
            global c
            global u
            global d
            global i
            global ssd
            folder = uigetdir();
            if ~isempty(c)
                imwrite (c, fullfile(folder, 'complement.bmp'));
            end
            if ~isempty(u)
                imwrite (u, fullfile(folder, 'union.bmp'));
            end
            if ~isempty(d)
                imwrite (d, fullfile(folder, 'difference.bmp'));
            end
            if ~isempty(i)
                imwrite (i, fullfile(folder, 'intersection.bmp'));
            end
            if ~isempty(ssd)
                imwrite (ssd, fullfile(folder, 'symdiff.bmp'));
            end
            clear global
        end

        % Close request function: UIFigure
        function close(app, event)
            clear global;
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            app.ImageSetOperationsLabel = uilabel(app.UIFigure);
            app.ImageSetOperationsLabel.Text = 'Image Set Operations';
            app.BrowseImage1Button = uibutton(app.UIFigure, 'push');
            app.BrowseImage1Button.ButtonPushedFcn = createCallbackFcn(app, @image1search, true);
            app.BrowseImage1Button.Text = 'Browse Image 1';
            app.BrowseImage2Button = uibutton(app.UIFigure, 'push');
            app.BrowseImage2Button.ButtonPushedFcn = createCallbackFcn(app, @search2, true);
            app.BrowseImage2Button.Text = 'Browse Image 2';
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.UnionCheckBox = uicheckbox(app.UIFigure);
            app.UnionCheckBox.ValueChangedFcn = createCallbackFcn(app, @un, true);
            app.UnionCheckBox.Text = 'Union';
            app.IntersectionCheckBox = uicheckbox(app.UIFigure);
            app.IntersectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @intersection, true);
            app.IntersectionCheckBox.Text = 'Intersection';
            app.complementCheckBox = uicheckbox(app.UIFigure);
            app.complementCheckBox.ValueChangedFcn = createCallbackFcn(app, @comp, true);
            app.complementCheckBox.Text = 'complement';
            app.SetDifferenceCheckBox = uicheckbox(app.UIFigure);
            app.SetDifferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @setdifference, true);
            app.SetDifferenceCheckBox.Text = 'Set Difference';
            app.SymmetricSetDifferenceCheckBox = uicheckbox(app.UIFigure);
            app.SymmetricSetDifferenceCheckBox.ValueChangedFcn = createCallbackFcn(app, @symSetDifference, true);
            app.SymmetricSetDifferenceCheckBox.Text = 'Symmetric Set Difference';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image 1')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Image 2')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Result')
            app.UIAxes_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageSet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end