classdef Log < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        LogTransformLabel  matlab.ui.control.Label
        BrowseImageButton  matlab.ui.control.Button
        SaveImagesButton   matlab.ui.control.Button
        ExitButton         matlab.ui.control.Button
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes_3           matlab.ui.control.UIAxes
        UIAxes_5           matlab.ui.control.UIAxes
        UIAxes_4           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function transform(app, event)
            global image1
            global image2
            global image3
            [filename,filepath] = uigetfile({'*.jpg';'*.png'});
            fullName = fullfile(filepath, filename);
            imageFile = imread(fullName);
            imageF = rgb2gray(imageFile);
            image = im2double(imageF);
            image1 = 2*log(1+image);
            image2 = 3*log(1+image);
            image3 = 4*log(1+image);
            imshow(imageF, 'Parent', app.UIAxes_4)
            imshow(image1, 'Parent', app.UIAxes_3)
            imshow(image2, 'Parent', app.UIAxes_2)
            imshow(image3, 'Parent', app.UIAxes_5)
            
        end

        % Button pushed function: SaveImagesButton
        function save(app, event)
            global image1
            global image2
            global image3
            folder = uigetdir();
            imwrite (image1, fullfile(folder, 'log2.bmp'));
            imwrite (image2, fullfile(folder, 'log3.bmp'));
            imwrite (image3, fullfile(folder, 'log4.bmp'));
        end

        % Button pushed function: ExitButton
        function exit(app, event)
            clear global;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LogTransformLabel = uilabel(app.UIFigure);
            app.LogTransformLabel.Text = 'Log Transform';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @transform, true);
            app.BrowseImageButton.Text = 'Browse Image';
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @exit, true);
            app.ExitButton.Text = 'Exit';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Transformed 4*log')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Transformed 2*log')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Transformed 3*log')
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Original')
            app.UIAxes_4.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Log

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end