classdef Negative < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        NegativeLabel              matlab.ui.control.Label
        SearchforInputImageButton  matlab.ui.control.Button
        SaveImageButton            matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function buttonpushed(app, event)
            clear global;
            app.delete;
        end

        % Button pushed function: SearchforInputImageButton
        function search(app, event)
            global negativeImage
            try
                [filename,filepath] = uigetfile({'*.jpg';'*.png'});
                fullname = [filepath filename];
                imageFile = imread(fullname);
                imshow(imageFile,'Parent', app.UIAxes)
                negativeImage = 255-imageFile;
                imshow(negativeImage,'Parent', app.UIAxes2);
                app.NegativeLabel.Enable = 'off';
            catch
                msg3 ='Please select an Image';
                errordlg(msg3);
                app.NegativeLabel.Enable = 'on';
                return;
            end
        end

        % Button pushed function: SaveImageButton
        function save(app, event)
            global negativeImage
            [file, path] = uiputfile({'*.jpg';'*.png';'*.bmp'});
            filename = fullfile(path,file);
            if ~isempty(negativeImage)
                imwrite (negativeImage, filename);
            end
            clear global
        end

        % Close request function: UIFigure
        function close(app, event)
            clear global;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            app.NegativeLabel = uilabel(app.UIFigure);
            app.NegativeLabel.Text = 'Negative';
            app.SearchforInputImageButton = uibutton(app.UIFigure, 'push');
            app.SearchforInputImageButton.ButtonPushedFcn = createCallbackFcn(app, @search, true);
            app.SearchforInputImageButton.Text = 'Search for Input Image';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImageButton.Text = 'Save Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Negative Image')
            app.UIAxes2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Negative

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end