classdef Threshhold < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ThresholdingandBinarizationLabel  matlab.ui.control.Label
        SaveImagesButton   matlab.ui.control.Button
        BrowseImageButton  matlab.ui.control.Button
        ThresholdButton    matlab.ui.control.Button
        BinarizeButton     matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        UIAxes_2           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImageButton
        function search(app, event)
            global image
            try
            [filename,filepath] = uigetfile({'*.jpg';'*.png'});
            fullname = [filepath filename];
            image = imread(fullname);
             catch 
                msg3 ='Please select an Image';
                errordlg(msg3);
            end
        end

        % Button pushed function: ThresholdButton
        function threshol(app, event)
            global image
            global I
            threshRGB = multithresh(image,7);
            value = [0 threshRGB(2:end) 255];
            I = imquantize(image, threshRGB, value);
            imshow(image,'parent', app.UIAxes);
            imshow(I,'parent', app.UIAxes_2);
            
        end

        % Button pushed function: BinarizeButton
        function bin(app, event)
            global image
            global b
            I = rgb2gray(image);
            b = imbinarize(I);
            imshow(image,'parent', app.UIAxes);
            imshow(b,'parent', app.UIAxes_2);
           
        end

        % Callback function
        function exit(app, event)
            clear global;
            app.delete;
        end

        % Button pushed function: SaveImagesButton
        function save(app, event)
            global I
            global b
            folder = uigetdir();
            if ~isempty(I)
            imwrite (I, fullfile(folder, 'threshold.bmp'));
            end
            if ~isempty(b)
            imwrite (b, fullfile(folder, 'binarize.bmp'));
            clear global;
            end
        end

        % Close request function: UIFigure
        function close(app, event)
            clear global;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            app.ThresholdingandBinarizationLabel = uilabel(app.UIFigure);
            app.ThresholdingandBinarizationLabel.Text = 'Thresholding and Binarization';
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.BrowseImageButton = uibutton(app.UIFigure, 'push');
            app.BrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @search, true);
            app.BrowseImageButton.Text = 'Browse Image ';
            app.ThresholdButton = uibutton(app.UIFigure, 'push');
            app.ThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @threshol, true);
            app.ThresholdButton.Text = 'Threshold';
            app.BinarizeButton = uibutton(app.UIFigure, 'push');
            app.BinarizeButton.ButtonPushedFcn = createCallbackFcn(app, @bin, true);
            app.BinarizeButton.Text = 'Binarize';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image 1')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Result')
            app.UIAxes_2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Threshhold

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end