classdef arithmatic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ArithmaticLabel         matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        ChooseanoperationLabel  matlab.ui.control.Label
        addCheckBox             matlab.ui.control.CheckBox
        subtractCheckBox        matlab.ui.control.CheckBox
        multiplyCheckBox        matlab.ui.control.CheckBox
        divideCheckBox          matlab.ui.control.CheckBox
        SaveImagesButton        matlab.ui.control.Button
        BrowseImage1Button_3    matlab.ui.control.Button
        BrowseImage2Button_2    matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function operation(app, event)
            
        end

        % Value changed function: addCheckBox
        function add(app, event)
            global image1
            global image2
            global a
            imshow(image1,'Parent', app.UIAxes);
            imshow(image2, 'Parent',app.UIAxes_2);
            a = imadd(image1, image2);
            imshow(a, 'Parent', app.UIAxes_3);
        end

        % Value changed function: subtractCheckBox
        function subtract(app, event)
            global image1
            global image2
            global s
            imshow(image1,'Parent', app.UIAxes);
            imshow(image2,'Parent', app.UIAxes_2)
            s = imsubtract(image1, image2);
            imshow(s, 'Parent', app.UIAxes_3);
        end

        % Value changed function: multiplyCheckBox
        function multiply(app, event)
            global image1;
            global image2;
            global m
            imshow(image2,'Parent', app.UIAxes_2)
            imshow(image1,'Parent', app.UIAxes);
            m = immultiply(image1,image2);
            imshow(m, 'Parent', app.UIAxes_3);
        end

        % Value changed function: divideCheckBox
        function divide(app, event)
            global image1
            global image2
            global d
            imshow(image2,'Parent', app.UIAxes_2)
            imshow(image1,'Parent', app.UIAxes);
            d = imdivide(image1, image2);
            imshow(d, 'Parent', app.UIAxes_3);
            
        end

        % Button pushed function: SaveImagesButton
        function save(app, event)
            global a
            global s
            global m
            global d
            folder = uigetdir();
            if ~isempty(s)
                imwrite (s, fullfile(folder, 'subtract.bmp'));
            end
            if ~isempty(m)
                imwrite (m, fullfile(folder, 'multiply.bmp'));
            end
            if ~isempty(d)
                imwrite (d, fullfile(folder, 'divide.bmp'));
            end
            if ~isempty(a)
                imwrite (a, fullfile(folder, 'add.bmp'));
            end
            clear global
        end

        % Close request function: UIFigure
        function close(app, event)
            clear global;
            app.delete;
        end

        % Button pushed function: BrowseImage1Button_3
        function browse1(app, event)
            global image1
            try
                [filename,filepath] = uigetfile({'*.jpg';'*.png'; '*.bmp'});
                fullName = fullfile(filepath, filename);
                img1 = imread(fullName);
                image1 = img1;
                app.BrowseImage1Button_3.Enable = 'off';
            catch
                msg3 ='Please select an Image';
                errordlg(msg3);
                app.BrowseImage1Button_3 = 'on';
            end
        end

        % Button pushed function: BrowseImage2Button_2
        function browse2(app, event)
            global image2
            global image1
            if isempty(image1)
                msg 'Please search for Image 1 first.';
                errordlg(msg);
                return;
            end
            try
                [filename,filepath] = uigetfile({'*.jpg';'*.png'; '*.bmp'});
                fullName = fullfile(filepath, filename);
                img2 = imread(fullName);
                [rows1, cols1, ~] = size(image1);
                [rows2, cols2, ~] = size(img2);
                if rows2 ~= rows1 || cols1 ~= cols2
                    img2 = imresize(img2, [rows1 cols1]);
                end
                if ndims(image1) == 2 && islogical(image1)
                    if ndims(img2) == 2 && islogical(img2)
                        image2 = img2;
                    else
                        msg2 = 'Image 1 is binary. both images must be.';
                        errordlg(msg2);
                        app.BrowseImage2Button_2.Enable = 'on';
                        return;
                    end
                end
                if ndims(img2) == 2 && islogical(img2)
                    if ndims(image1) ~= 2 && ~islogical(image1)
                        app.BrowseImage2Button_2.Enable = 'on';
                        msg5 = 'Image 1 is not Binary. Images must be of the same type.';
                        errordlg(msg5);
                        return;
                    end
                end
                image2 = img2;
                app.BrowseImage2Button_2.Enable = 'off';
            catch
                msg3 ='Please select an Image';
                errordlg(msg3);
                app.BrowseImage2Button_2.Enable = 'on';
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            app.ArithmaticLabel = uilabel(app.UIFigure);
            app.ArithmaticLabel.Text = 'Arithmatic';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1. Browse for Image 1 and Image 2.';
            app.ChooseanoperationLabel = uilabel(app.UIFigure);
            app.ChooseanoperationLabel.Text = '2. Choose an operation.';
            app.addCheckBox = uicheckbox(app.UIFigure);
            app.addCheckBox.ValueChangedFcn = createCallbackFcn(app, @add, true);
            app.addCheckBox.Text = 'add';
            app.subtractCheckBox = uicheckbox(app.UIFigure);
            app.subtractCheckBox.ValueChangedFcn = createCallbackFcn(app, @subtract, true);
            app.subtractCheckBox.Text = 'subtract';
            app.multiplyCheckBox = uicheckbox(app.UIFigure);
            app.multiplyCheckBox.ValueChangedFcn = createCallbackFcn(app, @multiply, true);
            app.multiplyCheckBox.Text = 'multiply';
            app.divideCheckBox = uicheckbox(app.UIFigure);
            app.divideCheckBox.ValueChangedFcn = createCallbackFcn(app, @divide, true);
            app.divideCheckBox.Text = 'divide';
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.BrowseImage1Button_3 = uibutton(app.UIFigure, 'push');
            app.BrowseImage1Button_3.ButtonPushedFcn = createCallbackFcn(app, @browse1, true);
            app.BrowseImage1Button_3.Text = 'Browse Image 1';
            app.BrowseImage2Button_2 = uibutton(app.UIFigure, 'push');
            app.BrowseImage2Button_2.ButtonPushedFcn = createCallbackFcn(app, @browse2, true);
            app.BrowseImage2Button_2.Text = 'Browse Image 2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image 1')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Image 2')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Resuting Image')
            app.UIAxes_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arithmatic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end