classdef logic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LogicalOperationsonBinaryImagesLabel  matlab.ui.control.Label
        ORCheckBox          matlab.ui.control.CheckBox
        XORCheckBox         matlab.ui.control.CheckBox
        NOTCheckBox         matlab.ui.control.CheckBox
        ANDCheckBox         matlab.ui.control.CheckBox
        BrowseImage1Button  matlab.ui.control.Button
        BrowseImage2Button  matlab.ui.control.Button
        SaveImagesButton    matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImage1Button
        function image1(app, event)
            global image1
            try
            [filename,filepath] = uigetfile({'*.jpg';'*.PNG'; '*.bmp'});
            fullName = fullfile(filepath, filename);
            image = imread(fullName);
            if ndims(image) == 2 && islogical(image)
                image1 = image;
            else
                msg = 'The image must be a Binary 2D image.';
                errordlg(msg);
                app.BrowseImage1Button.Enable = 'on';
                return;
            end
            app.BrowseImage1Button.Enable = 'off';
             catch
                msg3 ='Please select an Image';
                errordlg(msg3);
                app.BrowseImage1Button.Enable = 'on';
                return;
            end
            app.BrowseImage1Button.Enable = 'off';
        end

        % Button pushed function: BrowseImage2Button
        function image2(app, event)
            global image2
            global image1
            if isempty(image1)
                msg1 ='Please search for Image 1 first.';
                errordlg(msg1);
                app.BrowseImage2Button.Enable = 'on';
                return;
            end
            try
                [filename,filepath] = uigetfile({'*.jpg';'*.png'; '*.bmp'});
                fullName = fullfile(filepath, filename);
                img2 = imread(fullName);
                [rows1, cols1] = size(image1);
                [rows2, cols2] = size(img2);
                if rows2 ~= rows1 || cols1 ~= cols2
                    img2 = imresize(img2, [rows1 cols1]);
                end
                if ndims(img2) == 2 && islogical(img2)
                    image2 = img2;
                else
                    msg = 'The image must be a Binary 2D image.';
                    errordlg(msg);
                    app.BrowseImage2Button.Enable = 'on';
                    return;
                end
                app.BrowseImage2Button.Enable = 'off';
            catch
                msg3 ='Please select an Image';
                errordlg(msg3);
                app.BrowseImage2Button.Enable = 'on';
                return;
            end
            app.BrowseImage2Button.Enable = 'off';
            
        end

        % Value changed function: ANDCheckBox
        function AND(app, event)
            global image1
            global image2
            global a
            a = image1 & image2;
            imshow(image1, 'Parent', app.UIAxes);
            imshow(image2, 'Parent', app.UIAxes_2);
            imshow(a, 'Parent', app.UIAxes_3);
            
        end

        % Value changed function: NOTCheckBox
        function not(app, event)
            global image1
            global n
            hide = imread("hideimages\hide.PNG");
            n = ~image1;
            imshow(image1, 'Parent', app.UIAxes);
            imshow(hide, 'Parent', app.UIAxes_2);
            imshow(n, 'Parent', app.UIAxes_3);
            
        end

        % Value changed function: XORCheckBox
        function oor(app, event)
            global image1
            global image2
            global o
            o =xor( image1, image2);
            imshow(image1, 'Parent', app.UIAxes);
            imshow(image2, 'Parent', app.UIAxes_2);
            imshow(o, 'Parent', app.UIAxes_3);
        end

        % Value changed function: ORCheckBox
        function or(app, event)
            global image1
            global image2
            global r
            img1 = image1;
            img2 = image2;
            r = img1 | img2 ;
            imshow(image1, 'Parent', app.UIAxes);
            imshow(image2, 'Parent', app.UIAxes_2);
            imshow(r, 'Parent', app.UIAxes_3);
            
            
        end

        % Button pushed function: SaveImagesButton
        function save(app, event)
            global r
            global o
            global n
            global a
            folder = uigetdir();
            if ~isempty(r)
                imwrite (r, fullfile(folder, 'or.bmp'));
            end
            if ~isempty(o)
                imwrite (o, fullfile(folder, 'xor.bmp'));
            end
            if ~isempty(n)
                imwrite (n, fullfile(folder, 'not.bmp'));
            end
            if ~isempty(a)
                imwrite (a, fullfile(folder, 'and.bmp'));
            end
            clear global 
        end

        % Callback function
        function filename(app, event)
            
        end

        % Callback function
        function exit(app, event)
           
        end

        % Close request function: UIFigure
        function close(app, event)
            clear global;
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            app.LogicalOperationsonBinaryImagesLabel = uilabel(app.UIFigure);
            app.LogicalOperationsonBinaryImagesLabel.Text = 'Logical Operations on Binary Images';
            app.ORCheckBox = uicheckbox(app.UIFigure);
            app.ORCheckBox.ValueChangedFcn = createCallbackFcn(app, @or, true);
            app.ORCheckBox.Text = 'OR';
            app.XORCheckBox = uicheckbox(app.UIFigure);
            app.XORCheckBox.ValueChangedFcn = createCallbackFcn(app, @oor, true);
            app.XORCheckBox.Text = 'XOR';
            app.NOTCheckBox = uicheckbox(app.UIFigure);
            app.NOTCheckBox.ValueChangedFcn = createCallbackFcn(app, @not, true);
            app.NOTCheckBox.Text = 'NOT';
            app.ANDCheckBox = uicheckbox(app.UIFigure);
            app.ANDCheckBox.ValueChangedFcn = createCallbackFcn(app, @AND, true);
            app.ANDCheckBox.Text = 'AND';
            app.BrowseImage1Button = uibutton(app.UIFigure, 'push');
            app.BrowseImage1Button.ButtonPushedFcn = createCallbackFcn(app, @image1, true);
            app.BrowseImage1Button.Text = 'Browse Image 1';
            app.BrowseImage2Button = uibutton(app.UIFigure, 'push');
            app.BrowseImage2Button.ButtonPushedFcn = createCallbackFcn(app, @image2, true);
            app.BrowseImage2Button.Text = 'Browse Image 2';
            app.SaveImagesButton = uibutton(app.UIFigure, 'push');
            app.SaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImagesButton.Text = 'Save Images';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image 1')
            app.UIAxes.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Result')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Image 2')
            app.UIAxes_2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end