classdef powerlaw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PowerLawGammaLabel             matlab.ui.control.Label
        SubmitandBrowseImageButton     matlab.ui.control.Button
        EntercvalueEditFieldLabel      matlab.ui.control.Label
        EntercvalueEditField           matlab.ui.control.NumericEditField
        EntergammavalueEditFieldLabel  matlab.ui.control.Label
        EntergammavalueEditField       matlab.ui.control.NumericEditField
        SaveImageButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes_2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SubmitandBrowseImageButton
        function browse(app, event)
            global image1
            c = app.EntercvalueEditField.Value;
            g = app.EntergammavalueEditField.Value;
            if c==0 && g ==0
                msg = 'You must enter a number in the gamma and c value box';
                errordlg(msg);
                return;
            end
            try
                [filename,filepath] = uigetfile({'*.jpg';'*.png';'*.bmp'});
                fullName = fullfile(filepath, filename);
                imageFile = imread(fullName);
                imageF = rgb2gray(imageFile);
                image = im2double(imageF);
                image1 = c*(image.^g);
                imshow(imageF, 'Parent', app.UIAxes);
                imshow(image1, 'Parent', app.UIAxes_2);
            catch
                msg3 ='Please select an Image';
                errordlg(msg3);
            end
        end

        % Callback function
        function cValue(app, event)
            
        end

        % Callback function
        function gama(app, event)
            
        end

        % Callback function
        function EntergammavalueEditFieldValueChanged(app, event)
            
            
        end

        % Callback function
        function exit(app, event)
            clear global;
            app.delete;
        end

        % Button pushed function: SaveImageButton
        function save(app, event)
            global image1
            [file, path] = uiputfile({'*.jpg';'*.png';'*.bmp'});
            filename = fullfile(path,file);
            if ~isempty(image1)
                imwrite (image1, filename);
            end
            clear global
        end

        % Close request function: UIFigure
        function close(app, event)
            clear global;
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @close, true);
            app.PowerLawGammaLabel = uilabel(app.UIFigure);
            app.PowerLawGammaLabel.Text = 'Power Law / Gamma';
            app.SubmitandBrowseImageButton = uibutton(app.UIFigure, 'push');
            app.SubmitandBrowseImageButton.ButtonPushedFcn = createCallbackFcn(app, @browse, true);
            app.SubmitandBrowseImageButton.Text = 'Submit and Browse Image';
            app.EntercvalueEditFieldLabel = uilabel(app.UIFigure);
            app.EntercvalueEditFieldLabel.Text = 'Enter c value';
            app.EntercvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntergammavalueEditFieldLabel = uilabel(app.UIFigure);
            app.EntergammavalueEditFieldLabel.Text = 'Enter gamma value';
            app.EntergammavalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveImageButton.Text = 'Save Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original ')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Transform')
            app.UIAxes_2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = powerlaw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end